classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagAnalystUIFigure              matlab.ui.Figure
        ProjectMenu                     matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        AppGridLayout                   matlab.ui.container.GridLayout
        MessagesTabPanel                matlab.ui.container.TabGroup
        MessagesTab                     matlab.ui.container.Tab
        MessagesGridLayout              matlab.ui.container.GridLayout
        MessagesTextArea                matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        MagnetizationinputdataTab       matlab.ui.container.Tab
        GridLayoutMagnetizationInputData  matlab.ui.container.GridLayout
        GridLayoutInputPlot             matlab.ui.container.GridLayout
        GridLayoutInputTipsAndPlotButton  matlab.ui.container.GridLayout
        GridLayoutTips                  matlab.ui.container.GridLayout
        MTipField                       matlab.ui.control.EditField
        MtipAmLabel                     matlab.ui.control.Label
        HTipField                       matlab.ui.control.EditField
        HtipAmLabel                     matlab.ui.control.Label
        GridLayoutInputPlots            matlab.ui.container.GridLayout
        AxesRawInputData                matlab.ui.control.UIAxes
        AxesProcessedInputData          matlab.ui.control.UIAxes
        GridLayoutInputAxisScale        matlab.ui.container.GridLayout
        InputNumberofPointsEditField    matlab.ui.control.NumericEditField
        InputApplyPointsButton          matlab.ui.control.Button
        AxisscaleLabel                  matlab.ui.control.Label
        InputNumberofPointsLabel        matlab.ui.control.Label
        InputAxisScaleDropDown          matlab.ui.control.DropDown
        GridLayoutInput                 matlab.ui.container.GridLayout
        GridLayoutDatasetPath           matlab.ui.container.GridLayout
        InputDatasetPath                matlab.ui.control.EditField
        InputBrowseButton               matlab.ui.control.Button
        InputDatasetpathLabel           matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        DescriptionTextArea             matlab.ui.control.TextArea
        GridLayoutInputCurve            matlab.ui.container.GridLayout
        CurveDropDown                   matlab.ui.control.DropDown
        CurveDropDownLabel              matlab.ui.control.Label
        GridLayoutInputVerticalAxis     matlab.ui.container.GridLayout
        VerticalaxisfieldDropDown       matlab.ui.control.DropDown
        VerticalaxisfieldDropDownLabel  matlab.ui.control.Label
        GridLayoutInputHorizontalAxis   matlab.ui.container.GridLayout
        HorizontalaxisfieldDropDown     matlab.ui.control.DropDown
        HorizontalaxisfieldDropDownLabel  matlab.ui.control.Label
        AnhystereticmagnetizationfittingTab  matlab.ui.container.Tab
        AnhystereticmagnetizationfittingTabGridLayout  matlab.ui.container.GridLayout
        GridLayoutNumbers               matlab.ui.container.GridLayout
        FittedparametersLabel           matlab.ui.control.Label
        GridLayoutModeledCurve          matlab.ui.container.GridLayout
        PointSpaceDropDown              matlab.ui.control.DropDown
        NumberofpointsEditField         matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        NumberofcomponentsSpinner       matlab.ui.control.Spinner
        NumberofcomponentsSpinnerLabel  matlab.ui.control.Label
        ModeledcurveLabel               matlab.ui.control.Label
        TableQuantities                 matlab.ui.control.Table
        GridLayoutOtherQuantities       matlab.ui.container.GridLayout
        murinLabel                      matlab.ui.control.Label
        murinField                      matlab.ui.control.EditField
        JsField                         matlab.ui.control.EditField
        JsTEditFieldLabel               matlab.ui.control.Label
        OthercalculatedquantitiesLabel  matlab.ui.control.Label
        GridLayoutButtons               matlab.ui.container.GridLayout
        ErrorDisplay                    matlab.ui.control.EditField
        ErrortominimizeDropDown         matlab.ui.control.DropDown
        ErrortominimizeDropDownLabel    matlab.ui.control.Label
        CalculatePlotButton             matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        TableParameters                 matlab.ui.control.Table
        ModelretrievedparametersLabel   matlab.ui.control.Label
        TableFittedParameters           matlab.ui.control.Table
        GridLayoutAxes                  matlab.ui.container.GridLayout
        GridLayoutOptionsHdMdH          matlab.ui.container.GridLayout
        ShowhcrCheckBoxHdMdH            matlab.ui.control.CheckBox
        AxisscaleLabel_4                matlab.ui.control.Label
        AxisScaleDropDownHdMdH          matlab.ui.control.DropDown
        ShowgridCheckBoxHdMdH           matlab.ui.control.CheckBox
        PlotcomponentsCheckBoxHdMdH     matlab.ui.control.CheckBox
        ResidualplotButtondHdMdH        matlab.ui.control.Button
        GridLayoutOptionsdMdH           matlab.ui.container.GridLayout
        ShowhcrCheckBoxdMdH             matlab.ui.control.CheckBox
        AxisScaleDropDowndMdH           matlab.ui.control.DropDown
        AxisscaleLabel_3                matlab.ui.control.Label
        ShowgridCheckBoxdMdH            matlab.ui.control.CheckBox
        PlotcomponentsCheckBoxdMdH      matlab.ui.control.CheckBox
        ResidualplotButtondMdH          matlab.ui.control.Button
        GridLayoutOptionsM              matlab.ui.container.GridLayout
        ShowhcrCheckBoxM                matlab.ui.control.CheckBox
        AxisScaleDropDownM              matlab.ui.control.DropDown
        AxisscaleLabel_2                matlab.ui.control.Label
        SetColorsButton                 matlab.ui.control.Button
        ShowgridCheckBoxM               matlab.ui.control.CheckBox
        PlotcomponentsCheckBoxM         matlab.ui.control.CheckBox
        ResidualplotButtonM             matlab.ui.control.Button
        AxesHdMdH                       matlab.ui.control.UIAxes
        AxesdMdH                        matlab.ui.control.UIAxes
        AxesM                           matlab.ui.control.UIAxes
        MagnetizationoutputdataTab      matlab.ui.container.Tab
        GridLayoutMagnetizationoutputdata  matlab.ui.container.GridLayout
        GridLayoutExperimentalMagnetizationData  matlab.ui.container.GridLayout
        DropDownOutputExperimentalMagnetizationData  matlab.ui.control.DropDown
        EditFieldFileNameExperimentalMagnetizationData  matlab.ui.control.EditField
        ExperimentalanhystereticmagnetizationLabel  matlab.ui.control.Label
        CheckBoxExperimentalMagnetization  matlab.ui.control.CheckBox
        GridLayoutExportResiduesButton  matlab.ui.container.GridLayout
        ExportResiduesButton            matlab.ui.control.Button
        GridLayoutExportResiduesSemiLogMagDerivative  matlab.ui.container.GridLayout
        DropDownResiduesSemiLogMagDerivativeExtension  matlab.ui.control.DropDown
        EditFieldFileNameResiduesSemiLogMagDerivative  matlab.ui.control.EditField
        CheckBoxExportResiduesSemiLogMagDerivative  matlab.ui.control.CheckBox
        SemilogmagnetizationderivativeResiduesExportLabel  matlab.ui.control.Label
        GridLayoutExportResiduesSusceptibility  matlab.ui.container.GridLayout
        DropDownResiduesSusceptibilityExtension  matlab.ui.control.DropDown
        EditFieldFileNameResiduesSusceptibility  matlab.ui.control.EditField
        CheckBoxExportResiduesSusceptibility  matlab.ui.control.CheckBox
        SusceptibilityResiduesExportLabel  matlab.ui.control.Label
        ResidualplotsdataLabel          matlab.ui.control.Label
        GridLayoutExportResiduesMagnetization  matlab.ui.container.GridLayout
        DropDownResiduesMagnetizacionExtension  matlab.ui.control.DropDown
        EditFieldFileNameResiduesMagnetization  matlab.ui.control.EditField
        CheckBoxExportResiduesMagnetization  matlab.ui.control.CheckBox
        MagnetizationExportResiduesLabel  matlab.ui.control.Label
        GridLayoutExportPlotsButton     matlab.ui.container.GridLayout
        ExportPlotsButton               matlab.ui.control.Button
        GridLayoutExportPlotSemiLogMagDerivative  matlab.ui.container.GridLayout
        DropDownPlotSemiLogMagDerivativeExtension  matlab.ui.control.DropDown
        EditFieldFileNamePlotSemiLogMagDerivative  matlab.ui.control.EditField
        CheckBoxExportPlotSemiLogMagDerivative  matlab.ui.control.CheckBox
        SemilogmagnetizationderivativePlotExportLabel  matlab.ui.control.Label
        GridLayoutExportPlotSusceptibility  matlab.ui.container.GridLayout
        DropDownPlotSusceptibilityExtension  matlab.ui.control.DropDown
        EditFieldFileNamePlotSusceptibility  matlab.ui.control.EditField
        CheckBoxExportPlotSusceptibility  matlab.ui.control.CheckBox
        SusceptibilityPlotExportLabel   matlab.ui.control.Label
        GridLayoutExportPlotMagnetization  matlab.ui.container.GridLayout
        DropDownPlotMagnetizacionExtension  matlab.ui.control.DropDown
        EditFieldFileNamePlotMagnetization  matlab.ui.control.EditField
        CheckBoxExportPlotMagnetization  matlab.ui.control.CheckBox
        MagnetizationPlotExportLabel    matlab.ui.control.Label
        PlotsLabel                      matlab.ui.control.Label
        GridLayoutExportParametersButton  matlab.ui.container.GridLayout
        ExportErrorsCheckBox            matlab.ui.control.CheckBox
        ExportOtherquantitiesCheckBox   matlab.ui.control.CheckBox
        ExportParametersButton          matlab.ui.control.Button
        GridLayoutExportParametersFile  matlab.ui.container.GridLayout
        ExportModelparametersCheckBox   matlab.ui.control.CheckBox
        ExportFittedparametersCheckBox  matlab.ui.control.CheckBox
        DropDownOutputParametersExtension  matlab.ui.control.DropDown
        EditFieldFileNameParameters     matlab.ui.control.EditField
        ParametersLabel                 matlab.ui.control.Label
        GridLayoutExportData            matlab.ui.container.GridLayout
        ModeledanhystereticmagnetizationcomponentsLabel  matlab.ui.control.Label
        OutputSeparateComponentsCheckBox  matlab.ui.control.CheckBox
        ExportdataButton                matlab.ui.control.Button
        GridLayoutMagnetizationDataFittedAnhystereticMagnetization  matlab.ui.container.GridLayout
        DropDownOutputModeledAnhystereticMagnetizationExtension  matlab.ui.control.DropDown
        EditFieldFileNameModeledAnhystereticMagnetization  matlab.ui.control.EditField
        ModeledanhystereticmagnetizationLabel  matlab.ui.control.Label
        CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization  matlab.ui.control.CheckBox
        MagnetizationdataLabel          matlab.ui.control.Label
        GridLayoutOutputDatasetPath     matlab.ui.container.GridLayout
        OutputBrowseButton              matlab.ui.control.Button
        OutputDatasetPath               matlab.ui.control.EditField
        OutputDatasetpathLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppRoot
        H_raw
        M_raw
        data_curve
        modeled_curve
        Hcr
        mcr
        Hx
        magnetic_parameters
        number_components
        lb
        ub
        select_fit
        Colors
        ColorDialogApp
        ProjectPath
        fitted_parameter_values
        component_row_types
    end
    
    methods (Access = private)
        
        function plot(app)
            app.plot_M();
            app.plot_dMdH();
            app.plot_HdMdH();

            app.init_parameters_table(false);
            app.init_quantities_table(false);
            
            app.sync_fitted_parameter_values_from_components();
            app.refresh_table_value_display();
            offset = (3*app.number_components - 1);
            
            for i = 1:offset
                app.TableFittedParameters.Data(2*offset + i) = {app.format_short(str2double(app.TableFittedParameters.Data(2*offset + i)))};
                app.TableFittedParameters.Data(3*offset + i) = {app.format_short(str2double(app.TableFittedParameters.Data(3*offset + i)))};
            end
            app.JsField.Value = app.format_short(app.magnetic_parameters.Js);
            app.murinField.Value = app.format_engineering(app.magnetic_parameters.murin);

            if (app.ErrortominimizeDropDown.Value == "Diagonal")
                error_calculator = DiagonalErrorCalculator(app.data_curve, app.modeled_curve);
            end
            if (app.ErrortominimizeDropDown.Value == "Vertical")
                error_calculator = VerticalErrorCalculator(app.data_curve, app.modeled_curve);
            end
            if (app.ErrortominimizeDropDown.Value == "Horizontal")
                error_calculator = HorizontalErrorCalculator(app.data_curve, app.modeled_curve);
            end
            e = error_calculator.get_error();
            app.ErrorDisplay.Value = app.format_engineering(e);
        end

        function calculate_parameters(app)
            N = app.NumberofpointsEditField.Value;

            utils = Utils();

            [HTip, ~] = utils.find_tip(app.data_curve.H, app.data_curve.M);

            select_a = app.TableParameters.Data{1:app.number_components,5};
            app.magnetic_parameters = MagneticParameters(app.data_curve, app.Hcr, app.mcr, app.Hx, select_a);


            if(app.PointSpaceDropDown.Value == "log")
                Hhat = logspace(log10(app.data_curve.H(2)),log10(HTip),N-1);
            elseif(app.PointSpaceDropDown.Value == "linear")
                Hhat = linspace(app.data_curve.H(2),HTip,N-1);
            end

            Hhat = [0, Hhat];
            app.modeled_curve = ModeledAnhystereticCurve(Hhat, app.magnetic_parameters);
        end
        
        function fit_parameters(app)
            N = app.NumberofpointsEditField.Value;
            app.calculate_parameters()
            select_a = app.TableParameters.Data{1:app.number_components,5};
            fit_lb = zeros(app.number_components*3 - 1, 1);
            fit_ub = zeros(app.number_components*3 - 1, 1);
            fit_select_fit = cell(app.number_components*3 - 1, 1);
            for i=1:app.number_components
                fit_lb(i) = app.lb(2*i - 1);
                fit_lb(i + app.number_components) = app.lb(2*i);
                fit_ub(i) = app.ub(2*i - 1);
                fit_ub(i + app.number_components) = app.ub(2*i);
                fit_select_fit(i) = app.select_fit(2*i - 1);
                fit_select_fit(i + app.number_components) = app.select_fit(2*i);
            end
            for i=1:(app.number_components-1)
                fit_lb(i + 2*app.number_components) = app.lb(2*app.number_components + i);
                fit_ub(i + 2*app.number_components) = app.ub(2*app.number_components + i);
                fit_select_fit(i + 2*app.number_components) = app.select_fit(2*app.number_components + i);
            end
            
            app.write_message("Fitting started");
            pause(0.01);
            tic
            try
                [app.Hcr, app.mcr, app.Hx] = fit(app.data_curve, cat(2, app.Hcr, app.mcr, app.Hx), N, select_a, app.ErrortominimizeDropDown.Value, fit_lb, fit_ub, fit_select_fit);
                t = sprintf("%0.2f", toc);
                app.write_message("Fitting finished after " + t + " s");
            catch e
                t = sprintf("%0.2f", toc);
                app.write_message("Fitting failed after " + t + " s: " + e.message);
            end
        end
        
        function update_components(app)
            app.Hcr = zeros(1, app.number_components);
            app.mcr = zeros(1, app.number_components);
            app.Hx = zeros(1, max(app.number_components - 1, 0));
            offset = (3*app.number_components - 1);
            if offset > 0 && numel(app.fitted_parameter_values) >= offset && numel(app.component_row_types) >= offset
                hcr_index = 1;
                mcr_index = 1;
                hx_index = 1;
                for row = 1:offset
                    value = app.fitted_parameter_values(row);
                    switch app.component_row_types(row)
                        case "Hcr"
                            app.Hcr(hcr_index) = value;
                            hcr_index = hcr_index + 1;
                        case "m"
                            app.mcr(mcr_index) = value;
                            mcr_index = mcr_index + 1;
                        case "Hx"
                            app.Hx(hx_index) = value;
                            hx_index = hx_index + 1;
                    end
                end
            end
            app.lb = zeros(1, offset);
            app.ub = zeros(1, offset);
            app.select_fit = cell(1, offset);
            for i = 1:offset
                app.lb(i) = str2double(app.TableFittedParameters.Data(2*offset + i));
                app.ub(i) = str2double(app.TableFittedParameters.Data(3*offset + i));
                app.select_fit(i) = app.TableFittedParameters.Data(4*offset + i);
            end
        end
        
        function init_components(app)
            row_count = 3*app.number_components - 1;
            component_values = zeros(row_count, 1);
            lb_col = zeros(row_count, 1);
            ub_col = zeros(row_count, 1);
            row_names = cell(row_count, 1);
            row_types = strings(row_count, 1);
            for i = 1:app.number_components
                s = 'Hcr' + string(char(8320 + i));
                row_names(2*i - 1,:) = {convertStringsToChars(s + ' [A/m]')};
                component_values(2*i-1) = 0.01*i;
                row_types(2*i-1) = "Hcr";
                s = 'm' + string(char(8320 + i)) + ' (' + s + ')';
                row_names(2*i,:) = {convertStringsToChars(s)};
                component_values(2*i) = 0.521657107787896;
                row_types(2*i) = "m";
                lb_col(2*i-1) = 0;
                lb_col(2*i) = 0.4496;
                ub_col(2*i-1) = 1000000;
                ub_col(2*i) = 1;
            end

            for i = 1:(app.number_components-1)
                row_index = i + 2*app.number_components;
                s = 'Hx' + string(char(8320 + i)) + ' [A/m]';
                row_names(row_index,:) = {convertStringsToChars(s)};
                component_values(row_index) = i*0.015;
                row_types(row_index) = "Hx";
                lb_col(row_index) = 0;
                ub_col(row_index) = 1000000;
            end
            app.component_row_types = row_types;
            app.fitted_parameter_values = component_values;
            component_values = num2cell(component_values);
            lb_col = arrayfun(@(x) {app.format_short(x)}, lb_col);
            ub_col = arrayfun(@(x) {app.format_short(x)}, ub_col);
            app.select_fit = cell(row_count, 1);
            app.select_fit(:) = {true};
            t = table(row_names, component_values, lb_col, ub_col, app.select_fit);
            app.TableFittedParameters.Data = table2cell(t);
            app.refresh_table_value_display();

            app.init_parameters_table(true);
            app.init_quantities_table(true);
        end

        
        function plot_input(app)
            [HTip, MTip] = Utils().find_tip(app.data_curve.H, app.data_curve.M);
            app.MTipField.Value = app.format_short(MTip);
            app.HTipField.Value = app.format_short(HTip);

            cla(app.AxesProcessedInputData, 'reset')
            cla(app.AxesRawInputData, 'reset')
            plotter = Plotter(app.data_curve, app.modeled_curve, [], app.Colors, 5);

            axis_scale = string(app.InputAxisScaleDropDown.Value);
            if app.axis_scale_has_x(axis_scale)
                plotter.plot_raw_log(app.AxesProcessedInputData, app.data_curve.H, app.data_curve.M, 'H [A/m]', 'M [A/m]', 'Processed input data');
                plotter.plot_raw_log(app.AxesRawInputData, app.H_raw, app.M_raw, app.HorizontalaxisfieldDropDown.Value, app.VerticalaxisfieldDropDown.Value, 'Raw input data');
            else
                plotter.plot_raw(app.AxesProcessedInputData, app.data_curve.H, app.data_curve.M, 'H [A/m]', 'M [A/m]', 'Processed input data');
                plotter.plot_raw(app.AxesRawInputData, app.H_raw, app.M_raw, app.HorizontalaxisfieldDropDown.Value, app.VerticalaxisfieldDropDown.Value, 'Raw input data');
            end

            app.apply_axis_scale(app.AxesProcessedInputData, axis_scale);
            app.apply_axis_scale(app.AxesRawInputData, axis_scale);
        end

        function apply_axis_scale(app, ax, selection)
            selection = string(selection);
            if app.axis_scale_has_x(selection)
                ax.XScale = 'log';
            else
                ax.XScale = 'linear';
            end
            if app.axis_scale_has_y(selection)
                ax.YScale = 'log';
            else
                ax.YScale = 'linear';
            end
        end

        function has_x = axis_scale_has_x(~, selection)
            selection = string(selection);
            has_x = selection == "semilog-x" || selection == "log-log";
        end

        function has_y = axis_scale_has_y(~, selection)
            selection = string(selection);
            has_y = selection == "semilog-y" || selection == "log-log";
        end
        
        function init_parameters_table(app, default_values)
            parameters_col = cell(app.number_components, 1);
            for i = 1:app.number_components
                s = string(i);
                parameters_col(i,:) = {convertStringsToChars(s)};
            end

            Ms_col = cell(app.number_components, 1);
            alpha_col = cell(app.number_components, 1);
            a_col = cell(app.number_components, 1);

            select_a_col = cell(app.number_components, 1);
            for i = 1:app.number_components
                select_a_col(i,:) = {'low'};
            end
   
            if ~default_values
                select_a_col = app.TableParameters.Data{:,5};

                for i = 1:app.number_components
                    Ms_col(i,:) = {app.format_short(app.magnetic_parameters.Ms(i))};
                    alpha_col(i,:) = {app.format_engineering(app.magnetic_parameters.alpha(i))};
                    a_col(i,:) = {app.format_short(app.magnetic_parameters.a(i))};
                end
            end

            t = table(parameters_col, Ms_col, alpha_col, a_col, select_a_col);
            t.(5) = categorical(t.(5), {'high', 'low'}, 'Ordinal', true);

            app.TableParameters.Data = t;  
        end

        function init_quantities_table(app, default_values)
            parameters_col = cell(app.number_components, 1);
            for i = 1:app.number_components
                s = string(i);
                parameters_col(i,:) = {convertStringsToChars(s)};
            end

            dimensionless_alphaMs_col = cell(app.number_components, 1);
            density_product_col = cell(app.number_components, 1);
            Hk_col = cell(app.number_components, 1);
            initial_relative_magnetic_permeability_col = cell(app.number_components, 1);

            if ~default_values
                for i = 1:app.number_components
                    dimensionless_alphaMs_col(i,:) = {app.format_short(app.magnetic_parameters.dimensionless_alphaMs(i))};
                    density_product_col(i,:) = {app.format_short(app.magnetic_parameters.density_product(i))};
                    Hk_col(i,:) = {app.format_short(app.magnetic_parameters.Hk(i))};
%                     initial_relative_magnetic_permeability_col(i,:) = {app.format_thousands_only(app.magnetic_parameters.initial_relative_magnetic_permeability(i))};
                    initial_relative_magnetic_permeability_col(i,:) = {app.format_short(app.magnetic_parameters.initial_relative_magnetic_permeability(i))};
                end
            end

            t = table(parameters_col, dimensionless_alphaMs_col, density_product_col, Hk_col, initial_relative_magnetic_permeability_col);
            app.TableQuantities.Data = t;
        end

        function ret = format_short(~, v)
            string_value = char(sprintf("%0.4f",v));
            aux = regexp(string_value,'\.','split');
            aux{1} = fliplr(regexprep(fliplr(aux{1}),'\d{3}(?=\d)', '$0,'));
            ret = [aux{1},'.',aux{2}];
        end

        function ret = format_long(~, v)
            string_value = char(sprintf("%0.10f",v));
            aux = regexp(string_value,'\.','split');
            aux{1} = fliplr(regexprep(fliplr(aux{1}),'\d{3}(?=\d)', '$0,'));
            ret = [aux{1},'.',aux{2}];
        end

        function ret = format_engineering(~, v)
            ret = char(sprintf("%0.4e",v));
        end

        function refresh_table_value_display(app)
            row_count = size(app.TableFittedParameters.Data, 1);
            if row_count == 0 || isempty(app.component_row_types) || isempty(app.fitted_parameter_values)
                return;
            end
            valid_rows = min([row_count, numel(app.component_row_types), numel(app.fitted_parameter_values)]);
            for row = 1:valid_rows
                app.TableFittedParameters.Data(row, 2) = {app.format_value_for_display(row, app.fitted_parameter_values(row))};
            end
        end

        function sync_fitted_parameter_values_from_components(app)
            row_count = 3*app.number_components - 1;
            if row_count <= 0 || isempty(app.component_row_types)
                return;
            end
            values = zeros(row_count, 1);
            idx = 1;
            for i = 1:app.number_components
                values(idx) = app.Hcr(i);
                values(idx + 1) = app.mcr(i);
                idx = idx + 2;
            end
            for i = 1:(app.number_components - 1)
                values(idx) = app.Hx(i);
                idx = idx + 1;
            end
            app.fitted_parameter_values = values;
        end

        function ret = format_value_for_display(app, row, value)
            if row < 1 || row > numel(app.component_row_types)
                ret = char(sprintf("%g", value));
                return;
            end
            row_type = app.component_row_types(row);
            switch row_type
                case "m"
                    ret = app.format_m_display(value);
                otherwise
                    ret = app.format_short(value);
            end
        end

        function ret = format_value_for_edit(app, row)
            if row < 1 || row > numel(app.fitted_parameter_values)
                ret = "";
                return;
            end
            ret = char(sprintf("%.16g", app.fitted_parameter_values(row)));
        end

        function ret = format_m_display(~, value)
            ret = char(sprintf("%.2g", value));
        end

        function tf = is_m_row(app, row)
            tf = false;
            if row < 1 || row > numel(app.component_row_types)
                return;
            end
            tf = app.component_row_types(row) == "m";
        end

        function ret = format_thousands_only(~, v)
            string_value = char(sprintf("%d",round(v)));
            ret = fliplr(regexprep(fliplr(string_value),'\d{3}(?=\d)', '$0,'));
        end

        function plot_M(app)
            hcr_values = [];
            if app.ShowhcrCheckBoxM.Value == 1
                hcr_values = app.Hcr;
            end
            plotter = Plotter(app.data_curve, app.modeled_curve, hcr_values, app.Colors);
            cla(app.AxesM,'reset');
            plot_components = app.PlotcomponentsCheckBoxM.Value == 1;
            show_grid = app.ShowgridCheckBoxM.Value == 1;
            axis_scale = string(app.AxisScaleDropDownM.Value);
            if app.axis_scale_has_x(axis_scale)
                plotter.plot_M_log(app.AxesM, plot_components, show_grid);
            else
                plotter.plot_M(app.AxesM, plot_components, show_grid);
            end
            app.apply_axis_scale(app.AxesM, axis_scale);
        end

        function plot_dMdH(app)
            hcr_values = [];
            if app.ShowhcrCheckBoxdMdH.Value == 1
                hcr_values = app.Hcr;
            end
            plotter = Plotter(app.data_curve, app.modeled_curve, hcr_values, app.Colors);
            cla(app.AxesdMdH,'reset');
            plot_components = app.PlotcomponentsCheckBoxdMdH.Value == 1;
            show_grid = app.ShowgridCheckBoxdMdH.Value == 1;
            axis_scale = string(app.AxisScaleDropDowndMdH.Value);
            if app.axis_scale_has_x(axis_scale)
                plotter.plot_dMdH_log(app.AxesdMdH, plot_components, show_grid);
            else
                plotter.plot_dMdH(app.AxesdMdH, plot_components, show_grid);
            end
            app.apply_axis_scale(app.AxesdMdH, axis_scale);
        end
        
        function plot_HdMdH(app)
            hcr_values = [];
            if app.ShowhcrCheckBoxHdMdH.Value == 1
                hcr_values = app.Hcr;
            end
            plotter = Plotter(app.data_curve, app.modeled_curve, hcr_values, app.Colors);
            cla(app.AxesHdMdH,'reset');
            plot_components = app.PlotcomponentsCheckBoxHdMdH.Value == 1;
            show_grid = app.ShowgridCheckBoxHdMdH.Value == 1;
            axis_scale = string(app.AxisScaleDropDownHdMdH.Value);
            if app.axis_scale_has_x(axis_scale)
                plotter.plot_HdMdH_log(app.AxesHdMdH, plot_components, show_grid);
            else
                plotter.plot_HdMdH(app.AxesHdMdH, plot_components, show_grid);
            end
            app.apply_axis_scale(app.AxesHdMdH, axis_scale);
        end
        
        function results = get_time_string(~)
            results = string(datetime(datetime, 'Format', 'yy/MM/dd HH:mm:ss'));
        end
        
        function write_message(app, message)
            msg = sprintf("[%s] %s", app.get_time_string(), message);
            app.MessagesTextArea.Value(end+1) = cellstr(msg);
        end

        function import_data(app, path)
            number_of_points = app.InputNumberofPointsEditField.Value;
            H_unit = app.HorizontalaxisfieldDropDown.Value;
            M_unit = app.VerticalaxisfieldDropDown.Value;
            curve_type = app.CurveDropDown.Value;
            [H, M, app.H_raw, app.M_raw] = Parser(path, H_unit, M_unit, curve_type, number_of_points).import();
            
            app.data_curve = DataAnhystereticCurve(H, M);
        end

        function ret = subscript_to_number(~, str)
            chars = char(str);
            for i = 1:length(chars)
                if chars(i) >= 8272
                    chars(i) = 48 + chars(i) - '₀'; 
                end
            end
            ret = string(chars);
        end

        function export_residual(app, residue, file_name)
            t = table(transpose(app.data_curve.H), transpose(residue));
            t.Properties.VariableNames(:) = {'H [A/m]' 'residue'};

            path = fullfile(app.OutputDatasetPath.Value, file_name);
            writetable(t,path, 'Delimiter', ';');
            app.write_message("Data saved as " + file_name);
        end
        
        function save(app)
            file = fopen(app.ProjectPath,'w');

            s.fitted_parameters_value = app.fitted_parameter_values(:);
            s.fitted_parameters_lower_bound = app.TableFittedParameters.Data(:,3);
            s.fitted_parameters_upper_bound = app.TableFittedParameters.Data(:,4);
            s.number_components = app.number_components;
            s.select_a = app.TableParameters.Data.(5);
            s.number_points = app.NumberofpointsEditField.Value;
            s.point_space = app.PointSpaceDropDown.Value;
            s.error_type = app.ErrortominimizeDropDown.Value;
            s.input_path = app.InputDatasetPath.Value;
            s.horizontal_axis = app.HorizontalaxisfieldDropDown.Value;
            s.vertical_axis = app.VerticalaxisfieldDropDown.Value;
            s.curve_type = app.CurveDropDown.Value;
            s.description = app.DescriptionTextArea.Value;
            s.data_set_path = app.OutputDatasetPath.Value;

            s.experimental_anhysteretic_magnetization_file_name = app.EditFieldFileNameExperimentalMagnetizationData.Value;
            s.magnetization_file_name = app.EditFieldFileNameModeledAnhystereticMagnetization.Value;
            s.parameters_file_name = app.EditFieldFileNameParameters.Value;
            s.magnetization_plots_file_name = app.EditFieldFileNamePlotMagnetization.Value;
            s.suceptibility_plots_file_name = app.EditFieldFileNamePlotSusceptibility.Value;
            s.magnetization_derivative_file_name = app.EditFieldFileNamePlotSemiLogMagDerivative.Value;
            s.magnetization_residual_file_name = app.EditFieldFileNameResiduesMagnetization.Value;
            s.susceptibility_residual_file_name = app.EditFieldFileNameResiduesSusceptibility.Value;
            s.semi_log_derivative_file_name = app.EditFieldFileNameResiduesSemiLogMagDerivative.Value;

            s.input_axis_scale = app.InputAxisScaleDropDown.Value;
            s.axis_scale_m = app.AxisScaleDropDownM.Value;
            s.axis_scale_dMdH = app.AxisScaleDropDowndMdH.Value;
            s.axis_scale_hdMdH = app.AxisScaleDropDownHdMdH.Value;
            s.fitting_show_grid_checkbox = app.ShowgridCheckBoxHdMdH.Value;
            s.fitting_plot_components_checkbox = app.PlotcomponentsCheckBoxHdMdH.Value;
            s.fitting_show_hcr_checkbox = app.ShowhcrCheckBoxHdMdH.Value;

            s.model_magnetization_checkbox = app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization.Value;
            s.model_magnetization_components_checkbox = app.OutputSeparateComponentsCheckBox.Value;
            s.fitted_parameters_checkbox = app.ExportFittedparametersCheckBox.Value;
            s.model_parameters_checkbox = app.ExportModelparametersCheckBox.Value;
            s.other_quantities_checkbox = app.ExportOtherquantitiesCheckBox.Value;
            s.errors_checkbox = app.ExportErrorsCheckBox.Value;
            s.magnetization_plots_checkbox = app.CheckBoxExportPlotMagnetization.Value;
            s.susceptibility_plots_checkbox = app.CheckBoxExportPlotSusceptibility.Value;
            s.magnetization_derivatives_checkbox = app.CheckBoxExportPlotSemiLogMagDerivative.Value;

            s.experimental_anhysteretic_magnetization_checkbox = app.CheckBoxExperimentalMagnetization.Value;
            s.magnetization_residual_checkbox = app.CheckBoxExportResiduesMagnetization.Value;
            s.susceptibility_residual_checkbox = app.CheckBoxExportResiduesSusceptibility.Value;
            s.semi_log_derivative_residual_checkbox = app.CheckBoxExportResiduesSemiLogMagDerivative.Value;

            data = jsonencode(s, PrettyPrint=true);
            fprintf(file, "%s", data);
            fclose(file);
            app.write_message("Project saved at " + app.ProjectPath);
        end
    end
    
    methods (Access = public)
        
        function set_colors_and_plot(app, colors)
            app.Colors = colors;
            update_components(app)
            calculate_parameters(app)
            plot(app)
        end

        function a = calculate_and_plot(app)
            path = app.InputDatasetPath.Value;
            if isfile(path)
                app.import_data(path);
                update_components(app)
                calculate_parameters(app)
                app.plot_input();
                a = 0;
            else
                app.write_message(path + " was not found, please browse the dataseth path again");
                a = -1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(".\src");
            import_src();

            app.ProjectPath = "";

            app.number_components = app.NumberofcomponentsSpinner.Value;

            app.init_components();
            app.TableFittedParameters.ColumnFormat = {[] 'char' 'short' 'short' 'logical'};

            app.init_parameters_table(true);
            for i=1:5
                addStyle(app.TableParameters, uistyle('HorizontalAlignment','right'), "column", i)
            end
            
            app.init_quantities_table(true);
            for i=1:5
                addStyle(app.TableQuantities, uistyle('HorizontalAlignment','right'), "column", i)
            end

            update_components(app)
            % Each element from the array represents RGB on scale 0-1 
            app.Colors = [ 0.58 0 0.70; 0.70 0 0; 0 0 0.70; 0 0.70 0; 1 0.50 0];


            app.OutputDatasetPath.Value = strcat(pwd(), '\data');
            
            msg = sprintf("[%s] %s", app.get_time_string(), "MagAnalyst 1.0.3-beta");
            app.MessagesTextArea.Value(end) = cellstr(msg);
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            update_components(app)
            fit_parameters(app)
            calculate_parameters(app)
            plot(app)
        end

        % Button pushed function: CalculatePlotButton
        function CalculatePlotButtonPushed(app, event)
            update_components(app)
            calculate_parameters(app)
            plot(app)
        end

        % Callback function
        function logCheckBoxMValueChanged(app, event)
            app.plot_M()
        end

        % Callback function
        function logCheckBoxdMdHValueChanged(app, event)
            app.plot_dMdH()
        end

        % Callback function
        function logCheckBoxHdMdHValueChanged(app, event)
            app.plot_HdMdH()
        end

        % Value changed function: NumberofcomponentsSpinner
        function NumberofcomponentsSpinnerValueChanged(app, event)
            app.number_components = app.NumberofcomponentsSpinner.Value;
            app.init_components();
        end

        % Button pushed function: InputBrowseButton
        function InputBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.csv','Select dataset file', '.\data');
            if strcat(path, file) == ""
                return
            end

            try
                app.import_data(strcat(path, file));

                app.InputDatasetPath.Value = strcat(path, file);
                update_components(app)
                calculate_parameters(app)
                app.write_message("Imported " + file);
                app.plot_input();
            catch e
                app.write_message("Import failed: " + e.message);
            end
        end

        % Value changed function: InputDatasetPath
        function InputDatasetPathValueChanged(app, event)
            dataset_path = app.InputDatasetPath.Value;
            [app.H, app.M] = Parser(dataset_path).get_data_csv;
            update_components(app)
            calculate_parameters(app)
        end

        % Callback function
        function logCheckBoxInputPlotValueChanged(app, event)
            if app.InputDatasetPath.Value == ""
                return
            end
            app.plot_input()
        end

        % Button pushed function: ResidualplotButtonM
        function ResidualplotButtonMPushed(app, event)
            residue_calculator = MagnetizationResidueCalculator(app.data_curve, app.modeled_curve);
            residue = residue_calculator.get_residue();
            log_flag = app.axis_scale_has_x(string(app.AxisScaleDropDownM.Value));
            residue_plotter = ResiduePlotter(app.data_curve.H, app.data_curve.M, app.modeled_curve.H, app.modeled_curve.M, residue, log_flag, "M [A/m]");
            residue_plotter.plot()
        end

        % Button pushed function: ResidualplotButtondMdH
        function ResidualplotButtondMdHPushed(app, event)
            residue_calculator = SusceptibilityResidueCalculator(app.data_curve, app.modeled_curve);
            residue = residue_calculator.get_residue();
            log_flag = app.axis_scale_has_x(string(app.AxisScaleDropDowndMdH.Value));
            residue_plotter = ResiduePlotter(app.data_curve.H, app.data_curve.dMdH, app.modeled_curve.H, app.modeled_curve.dMdH, residue, log_flag, "∂M/∂H");
            residue_plotter.plot()
        end

        % Button pushed function: ResidualplotButtondHdMdH
        function ResidualplotButtondHdMdHPushed(app, event)
            residue_calculator = SemilogDerivativeResidueCalculator(app.data_curve, app.modeled_curve);
            residue = residue_calculator.get_residue();
            log_flag = app.axis_scale_has_x(string(app.AxisScaleDropDownHdMdH.Value));
            residue_plotter = ResiduePlotter(app.data_curve.H, app.data_curve.HdMdH, app.modeled_curve.H, app.modeled_curve.HdMdH, residue, log_flag, "∂M/∂(logH) [A/m]");
            residue_plotter.plot()
        end

        % Button pushed function: OutputBrowseButton
        function OutputBrowseButtonPushed(app, event)
            app.OutputDatasetPath.Value = uigetdir(app.OutputDatasetPath.Value,'Select output folder');
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            if (app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization.Value == 0 && app.CheckBoxExperimentalMagnetization.Value == 0)
                app.write_message("No magnetization data was selected to be exported.");
                return;
            end
            if (app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization.Value == 1)
                if(app.OutputSeparateComponentsCheckBox.Value == 0)
                    t = table(transpose(app.modeled_curve.H), transpose(app.modeled_curve.M), transpose(app.modeled_curve.dMdH), transpose(app.modeled_curve.HdMdH));
                    t.Properties.VariableNames(:) = {'H [A/m]' 'M [A/m]' 'dM/dH' 'dM/dlogH [A/m]' };
                elseif(app.OutputSeparateComponentsCheckBox.Value == 1)
                    t = table(transpose(app.modeled_curve.H), transpose(app.modeled_curve.M), array2table(transpose(app.modeled_curve.Mi)), transpose(app.modeled_curve.dMdH), array2table(transpose(app.modeled_curve.dMidH)), transpose(app.modeled_curve.HdMdH), array2table(transpose(app.modeled_curve.HdMidH)));
                    t = splitvars(t);
                    variable_names = cell(4 + app.number_components*3, 1);
                    variable_names(1) = {'H [A/m]'};
                    variable_names(2) = {'M [A/m]'};
                    variable_names(app.number_components + 3) = {'dM/dH'};
                    variable_names(2*app.number_components + 4) = {'dM/dlogH [A/m]'};
                    for i=1:app.number_components
                        variable_names(i + 2) = cellstr(strcat('M', string(i), ' [A/m]'));
                        variable_names(i + app.number_components + 3) = cellstr(strcat('dM', string(i), '/dH'));
                        variable_names(i + 2*app.number_components + 4) = cellstr(strcat('dM', string(i), '/dlogH [A/m]'));
                    end
                    t.Properties.VariableNames = variable_names;
                end
                file_name = strcat(app.EditFieldFileNameModeledAnhystereticMagnetization.Value, app.DropDownOutputModeledAnhystereticMagnetizationExtension.Value);
                path = strcat(app.OutputDatasetPath.Value, '\', file_name);
                writetable(t,path, 'Delimiter', ';');
                app.write_message("Modeled data saved as " + file_name);
            end
            %Separate into two functions
            if(app.CheckBoxExperimentalMagnetization.Value == 1)
                t = table(transpose(app.data_curve.H), transpose(app.data_curve.M));
                t.Properties.VariableNames(:) = {'H [A/m]' 'M [A/m]'};
                file_name = strcat(app.EditFieldFileNameExperimentalMagnetizationData.Value, app.DropDownOutputExperimentalMagnetizationData.Value);
                path = strcat(app.OutputDatasetPath.Value, '\', file_name);
                writetable(t,path, 'Delimiter', ';');
                app.write_message("Experimental data saved as " + file_name);
            end
        end

        % Value changed function: PlotcomponentsCheckBoxM
        function PlotcomponentsCheckBoxMValueChanged(app, event)
            app.plot_M()
        end

        % Value changed function: PlotcomponentsCheckBoxdMdH
        function PlotcomponentsCheckBoxdMdHValueChanged(app, event)
            app.plot_dMdH()
        end

        % Value changed function: PlotcomponentsCheckBoxHdMdH
        function PlotcomponentsCheckBoxHdMdHValueChanged(app, event)
            app.plot_HdMdH()
        end

        % Value changed function: ShowgridCheckBoxM
        function ShowgridCheckBoxMValueChanged(app, event)
            grid(app.AxesM,"off");
            if(app.ShowgridCheckBoxM.Value == 1)
                grid(app.AxesM,"on");
            end 
        end

        % Value changed function: ShowgridCheckBoxdMdH
        function ShowgridCheckBoxdMdHValueChanged(app, event)
            grid(app.AxesdMdH,"off");
            if(app.ShowgridCheckBoxdMdH.Value == 1)
                grid(app.AxesdMdH,"on");
            end
        end

        % Value changed function: ShowgridCheckBoxHdMdH
        function ShowgridCheckBoxHdMdHValueChanged(app, event)
            grid(app.AxesHdMdH,"off");
            if(app.ShowgridCheckBoxHdMdH.Value == 1)
                grid(app.AxesHdMdH,"on");
            end
        end

        % Button pushed function: SetColorsButton
        function SetColorsButtonPushed(app, event)
            app.SetColorsButton.Enable = false;
            app.ColorDialogApp = colorDialog(app, app.Colors, app.number_components);
        end

        % Close request function: MagAnalystUIFigure
        function MagAnalystUIFigureCloseRequest(app, event)
            delete(app.ColorDialogApp)
            delete(app)
        end

        % Button pushed function: ExportParametersButton
        function ExportParametersButtonPushed(app, event)
            if(app.ExportFittedparametersCheckBox.Value == 0 && app.ExportModelparametersCheckBox.Value == 0 && app.ExportOtherquantitiesCheckBox.Value == 0 && app.ExportErrorsCheckBox.Value == 0)
                app.write_message("No parameters were selected to be exported");
                return;
            end
            file_name = strcat(app.EditFieldFileNameParameters.Value, app.DropDownOutputParametersExtension.Value);
            path = strcat(app.OutputDatasetPath.Value, '\', file_name);
            file = fopen(path,'w');
            fprintf(file, "Parameters:" + newline);
            if(app.ExportFittedparametersCheckBox.Value == 1)
                fprintf(file, "Fitted Parameters:" + newline);
                for i = 1:height(app.TableFittedParameters.Data)
                    name = string(app.TableFittedParameters.Data(i, 1));
                    name = subscript_to_number(app, name);
                    value = str2double(app.TableFittedParameters.Data(i, 2));
                    s = sprintf("%s: \t%f", name, value);
                    fprintf(file, s + newline);
                end
                fprintf(file, newline);
            end     
            if(app.ExportModelparametersCheckBox.Value == 1)
                fprintf(file, "Model-Retrieved Parameters:" + newline);
                for i = 1:height(app.TableParameters.Data)
                    Ms_value = str2double(app.TableParameters.Data(i, 2).Ms_col);
                    alpha_value = str2double(app.TableParameters.Data(i, 3).alpha_col);
                    a_value = str2double(app.TableParameters.Data(i, 4).a_col);
                    s_component = sprintf("Component: %i", i);
                    s_Ms_value = sprintf("    Ms%i [A/m]: \t%0.4f", i, Ms_value);
                    s_alpha_value = sprintf("            α: \t%0.4e", alpha_value);
                    s_a_value = sprintf("     a%i [A/m]: \t%0.4f", i, a_value);
                    fprintf(file, s_component + newline + s_Ms_value + newline + s_alpha_value + newline + s_a_value + newline);
                end
                fprintf(file, newline);
            end
            if(app.ExportOtherquantitiesCheckBox.Value == 1)
            fprintf(file, "Calculated Quantities:" + newline);
                for i = 1:height(app.TableQuantities.Data)
                    alpha_Ms_value = str2double(app.TableQuantities.Data(i, 2).dimensionless_alphaMs_col);
                    density_product_value = str2double(app.TableQuantities.Data(i, 3).density_product_col);
                    Hk_value = str2double(app.TableQuantities.Data(i, 4).Hk_col);
                    magnetic_permeability_value = str2double(app.TableQuantities.Data(i, 5).initial_relative_magnetic_permeability_col);
                    s_component = sprintf("Component: %i", i);
                    s_alpha_Ms_value = sprintf("    α%i|Ms%i|/(3a%i): \t%0.4f", i, i, i, alpha_Ms_value);
                    s_density_product_value = sprintf("    N%ikBT [J/m^3]: \t%0.4f", i, density_product_value);
                    s_Hk = sprintf("        Hk%i [A/m]: \t%0.4f", i, Hk_value);
                    s_magnetic_permeability_value = sprintf("            μrin%i: \t%i", i, magnetic_permeability_value);
                    fprintf(file, s_component + newline + s_alpha_Ms_value + newline + s_density_product_value + newline + s_Hk + newline + s_magnetic_permeability_value + newline);
                end
                fprintf(file, newline);
            end

            if (app.ExportErrorsCheckBox.Value == 1)
                diagonal_error = DiagonalErrorCalculator(app.data_curve, app.modeled_curve).get_error();
                horizontal_error = HorizontalErrorCalculator(app.data_curve, app.modeled_curve).get_error();
                vertical_error = VerticalErrorCalculator(app.data_curve, app.modeled_curve).get_error();
                s_diagonal_error = sprintf("  Diagonal error: \t%10.4e", diagonal_error);
                s_horizontal_error = sprintf("Horizontal error: \t%10.4e", horizontal_error);
                s_vertical_error = sprintf("  Vertical error: \t%10.4e", vertical_error);
                s = "Errors:" + newline + s_diagonal_error + newline + s_horizontal_error + newline + s_vertical_error + newline;
                fprintf(file, s + newline);
            end

            fclose(file);
            app.write_message("Parameter data saved as " + file_name);
        end

        % Button pushed function: ExportPlotsButton
        function ExportPlotsButtonPushed(app, event)
            if (app.CheckBoxExportPlotMagnetization.Value == 0 && app.CheckBoxExportPlotSusceptibility.Value == 0 && app.CheckBoxExportPlotSemiLogMagDerivative.Value == 0)
                app.write_message("No plots were selected to be exported");
                return;
            end
            app.write_message("Exporting plots");

            if (app.CheckBoxExportPlotMagnetization.Value == 1)
                file_name = strcat(app.EditFieldFileNamePlotMagnetization.Value, app.DropDownPlotMagnetizacionExtension.Value);
                path = strcat(app.OutputDatasetPath.Value, '\', file_name);
                exportgraphics(app.AxesM,path,'Resolution',400);
                message = strcat("Magnetization plots exported as ",file_name);
                app.write_message(message);
            end

            if (app.CheckBoxExportPlotSusceptibility.Value == 1)
                file_name = strcat(app.EditFieldFileNamePlotSusceptibility.Value, app.DropDownPlotSusceptibilityExtension.Value);
                path = strcat(app.OutputDatasetPath.Value, '\', file_name);
                exportgraphics(app.AxesdMdH,path,'Resolution',400);
                message = strcat("Susceptibility plots exported as ",file_name);
                app.write_message(message);
            end

            if(app.CheckBoxExportPlotSemiLogMagDerivative.Value == 1)
                file_name = strcat(app.EditFieldFileNamePlotSemiLogMagDerivative.Value, app.DropDownPlotSemiLogMagDerivativeExtension.Value);
                path = strcat(app.OutputDatasetPath.Value, '\', file_name);
                exportgraphics(app.AxesHdMdH,path,'Resolution',400);
                message = strcat("Semi-Log Magnetization Derivative plots exported as ",file_name);
                app.write_message(message);
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
          [file,path] = uiputfile('*.txt','Save project', '.\data\project.txt');
          app.ProjectPath = strcat(path, file);
          app.save();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.write_message("Opening new project");
            pause(0.01);
            [file,path] = uigetfile('*.txt','Select project', '.\data');
            app.ProjectPath = strcat(path, file);

            data = fileread(app.ProjectPath);
            s = jsondecode(data);

            app.number_components = s.number_components;
            app.init_components();
            app.init_parameters_table(true);
            app.init_quantities_table(true);
            app.NumberofcomponentsSpinner.Value = app.number_components;

            app.TableFittedParameters.Data(:,2) = s.fitted_parameters_value;
            app.TableFittedParameters.Data(:,3) = s.fitted_parameters_lower_bound;
            app.TableFittedParameters.Data(:,4) = s.fitted_parameters_upper_bound;


            app.TableParameters.Data.(5) = cellstr(s.select_a);
            app.TableParameters.Data.(5) = categorical(app.TableParameters.Data.(5), {'high', 'low'}, 'Ordinal', true);
            
            app.NumberofpointsEditField.Value = s.number_points;
            app.PointSpaceDropDown.Value = s.point_space;
            app.ErrortominimizeDropDown.Value = s.error_type;
            app.InputDatasetPath.Value = s.input_path;
            app.HorizontalaxisfieldDropDown.Value = s.horizontal_axis;
            app.VerticalaxisfieldDropDown.Value = s.vertical_axis;
            app.CurveDropDown.Value = s.curve_type;
            app.DescriptionTextArea.Value = s.description;
            
            app.OutputDatasetPath.Value = s.data_set_path;
            app.EditFieldFileNameExperimentalMagnetizationData.Value = s.experimental_anhysteretic_magnetization_file_name;
            app.EditFieldFileNameModeledAnhystereticMagnetization.Value = s.magnetization_file_name; 
            app.EditFieldFileNameParameters.Value = s.parameters_file_name;
            app.EditFieldFileNamePlotMagnetization.Value = s.magnetization_plots_file_name;
            app.EditFieldFileNamePlotSusceptibility.Value = s.suceptibility_plots_file_name;
            app.EditFieldFileNamePlotSemiLogMagDerivative.Value = s.magnetization_derivative_file_name;
            app.EditFieldFileNameResiduesMagnetization.Value = s.magnetization_residual_file_name;
            app.EditFieldFileNameResiduesSusceptibility.Value = s.susceptibility_residual_file_name;
            app.EditFieldFileNameResiduesSemiLogMagDerivative.Value = s.semi_log_derivative_file_name;

            app.ShowgridCheckBoxHdMdH.Value = s.fitting_show_grid_checkbox;
            app.PlotcomponentsCheckBoxHdMdH.Value = s.fitting_plot_components_checkbox;
            if isfield(s, 'fitting_show_hcr_checkbox')
                show_hcr_value = s.fitting_show_hcr_checkbox;
            else
                show_hcr_value = 1;
            end
            app.ShowhcrCheckBoxM.Value = show_hcr_value;
            app.ShowhcrCheckBoxdMdH.Value = show_hcr_value;
            app.ShowhcrCheckBoxHdMdH.Value = show_hcr_value;
            app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization.Value = s.model_magnetization_checkbox;
            app.OutputSeparateComponentsCheckBox.Value = s.model_magnetization_components_checkbox;
            app.ExportFittedparametersCheckBox.Value = s.fitted_parameters_checkbox;
            app.ExportModelparametersCheckBox.Value = s.model_parameters_checkbox;
            app.ExportOtherquantitiesCheckBox.Value = s.other_quantities_checkbox;
            app.ExportErrorsCheckBox.Value = s.errors_checkbox;
            app.CheckBoxExportPlotMagnetization.Value = s.magnetization_plots_checkbox;
            app.CheckBoxExportPlotSusceptibility.Value = s.susceptibility_plots_checkbox;
            app.CheckBoxExportPlotSemiLogMagDerivative.Value = s.magnetization_derivatives_checkbox;

            app.CheckBoxExperimentalMagnetization.Value = s.experimental_anhysteretic_magnetization_checkbox;
            app.CheckBoxExportResiduesMagnetization.Value = s.magnetization_residual_checkbox;
            app.CheckBoxExportResiduesSusceptibility.Value = s.susceptibility_residual_checkbox;
            app.CheckBoxExportResiduesSemiLogMagDerivative.Value = s.semi_log_derivative_residual_checkbox;
            
           
            if (app.calculate_and_plot() == -1)
                return
            end
            app.CalculatePlotButtonPushed();
            app.write_message(file + " was opened successfully");
        end

        % Button pushed function: ExportResiduesButton
        function ExportResiduesButtonPushed(app, event)
            if (app.CheckBoxExportResiduesMagnetization.Value == 0 && app.CheckBoxExportResiduesSusceptibility.Value == 0 && app.CheckBoxExportResiduesSemiLogMagDerivative.Value == 0)
                app.write_message("No residual plots were selected to be exported");
                return;
            end
            if (app.CheckBoxExportResiduesMagnetization.Value == 1)
                residue = MagnetizationResidueCalculator(app.data_curve, app.modeled_curve).get_residue();
                file_name = strcat(app.EditFieldFileNameResiduesMagnetization.Value, app.DropDownResiduesMagnetizacionExtension.Value);
                app.export_residual(residue, file_name);
            end

            if(app.CheckBoxExportResiduesSusceptibility.Value == 1)
                residue = SusceptibilityResidueCalculator(app.data_curve, app.modeled_curve).get_residue();
                file_name = strcat(app.EditFieldFileNameResiduesSusceptibility.Value, app.DropDownResiduesSusceptibilityExtension.Value);
                app.export_residual(residue, file_name);
            end

            if(app.CheckBoxExportResiduesSemiLogMagDerivative.Value == 1)
                residue = SemilogDerivativeResidueCalculator(app.data_curve, app.modeled_curve).get_residue();
                file_name = strcat(app.EditFieldFileNameResiduesSemiLogMagDerivative.Value, app.DropDownResiduesSemiLogMagDerivativeExtension.Value);
                app.export_residual(residue, file_name);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if (app.ProjectPath == "")
                app.SaveasMenuSelected();
            else
                app.save();
            end
        end

        % Value changed function: HorizontalaxisfieldDropDown
        function HorizontalaxisfieldDropDownValueChanged(app, event)
            app.calculate_and_plot();
        end

        % Value changed function: VerticalaxisfieldDropDown
        function VerticalaxisfieldDropDownValueChanged(app, event)
            app.calculate_and_plot();
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.InputAxisScaleDropDown.Value;
            app.plot_input();
            app.apply_axis_scale(app.AxesProcessedInputData, value);
            app.apply_axis_scale(app.AxesRawInputData, value);
        end

        % Callback function
        function InputAxisScaleDropDownValueChanged(app, event)
            app.InputAxisScaleDropDown.Value;
            app.plot_HdMdH();
            axis_scale = string(app.AxisScaleDropDownHdMdH.Value);
            app.apply_axis_scale(app.AxesHdMdH, axis_scale);
        end

        % Value changed function: AxisScaleDropDownM
        function AxisScaleDropDownMValueChanged(app, event)
            app.AxisScaleDropDownM.Value;
            axis_scale = string(app.AxisScaleDropDownM.Value);
            app.apply_axis_scale(app.AxesM, axis_scale);
        end

        % Value changed function: AxisScaleDropDowndMdH
        function AxisScaleDropDowndMdHValueChanged(app, event)
            app.AxisScaleDropDowndMdH.Value;
            axis_scale = string(app.AxisScaleDropDowndMdH.Value);
            app.apply_axis_scale(app.AxesdMdH, axis_scale); 
        end

        % Value changed function: AxisScaleDropDownHdMdH
        function AxisScaleDropDownHdMdHValueChanged(app, event)
            app.AxisScaleDropDownHdMdH.Value;
            
        end

        % Value changed function: CurveDropDown
        function CurveDropDownValueChanged(app, event)
            value = app.CurveDropDown.Value;
            dataset_path = app.InputDatasetPath.Value;
            if dataset_path == ""
                app.write_message("Select a dataset before applying point count.");
                return
            end
        
            try
                app.import_data(dataset_path);
                update_components(app)
                calculate_parameters(app)
                app.plot_input();
            catch e
                app.write_message("Reprocessing failed: " + e.message);
            end
        end

        % Button pushed function: InputApplyPointsButton
        function InputApplyPointsButtonPushed(app, event)
            
            dataset_path = app.InputDatasetPath.Value;
            if dataset_path == ""
                app.write_message("Select a dataset before applying point count.");
                return
            end
        
            try
                app.import_data(dataset_path);
                update_components(app)
                calculate_parameters(app)
                app.plot_input();
            catch e
                app.write_message("Reprocessing failed: " + e.message);
            end
        
        end

        % Value changed function: InputAxisScaleDropDown
        function InputAxisScaleDropDownValueChanged2(app, event)

            dataset_path = app.InputDatasetPath.Value;
            if dataset_path == ""
                app.write_message("Select a dataset before applying point count.");
                return
            end
        
            try
                app.import_data(dataset_path);
                update_components(app)
                calculate_parameters(app)
                app.plot_input();
            catch e
                app.write_message("Reprocessing failed: " + e.message);
            end
        end

        % Cell edit callback: TableFittedParameters
        function TableFittedParametersCellEdit(app, event)
            if isempty(event.Indices)
                return
            end
            row = event.Indices(1,1);
            col = event.Indices(1,2);
            if col ~= 2 || row > size(app.TableFittedParameters.Data, 1) || row > numel(app.fitted_parameter_values)
                return
            end
            new_text = string(event.NewData);
            new_text = strrep(new_text, ',', '');
            new_value = str2double(new_text);
            if isnan(new_value) || ~isfinite(new_value)
                app.write_message("Invalid fitted parameter value");
            else
                app.fitted_parameter_values(row) = new_value;
                update_components(app);
            end
            app.refresh_table_value_display();
        end

        % Cell selection callback: TableFittedParameters
        function TableFittedParametersCellSelection(app, event)
            if isempty(event.Indices)
                return
            end
            row = event.Indices(1,1);
            col = event.Indices(1,2);
            if row > size(app.TableFittedParameters.Data, 1)
                return
            end
            app.refresh_table_value_display();
            if col == 2 && app.is_m_row(row)
                app.TableFittedParameters.Data(row, 2) = {app.format_value_for_edit(row)};
            end
            
        end

        % Value changed function: ShowhcrCheckBoxM
        function ShowhcrCheckBoxMValueChanged(app, event)
            app.plot_M()
        end

        % Value changed function: ShowhcrCheckBoxdMdH
        function ShowhcrCheckBoxdMdHValueChanged(app, event)
            app.plot_dMdH()
        end

        % Value changed function: ShowhcrCheckBoxHdMdH
        function ShowhcrCheckBoxHdMdHValueChanged(app, event)
            app.plot_HdMdH()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MagAnalystUIFigure = uifigure('Visible', 'off');
            app.MagAnalystUIFigure.Name = 'MagAnalyst';
            app.MagAnalystUIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'logo.png');
            app.MagAnalystUIFigure.CloseRequestFcn = createCallbackFcn(app, @MagAnalystUIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.MagAnalystUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.ProjectMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Separator = 'on';
            app.SaveasMenu.Text = 'Save as...';
            app.AppGridLayout = uigridlayout(app.MagAnalystUIFigure);
            app.TabGroup = uitabgroup(app.AppGridLayout);
            app.MagnetizationinputdataTab = uitab(app.TabGroup);
            app.MagnetizationinputdataTab.Title = 'Magnetization input data';
            app.GridLayoutMagnetizationInputData = uigridlayout(app.MagnetizationinputdataTab);
            app.GridLayoutInput = uigridlayout(app.GridLayoutMagnetizationInputData);
            app.GridLayoutInputHorizontalAxis = uigridlayout(app.GridLayoutInput);
            app.HorizontalaxisfieldDropDownLabel = uilabel(app.GridLayoutInputHorizontalAxis);
            app.HorizontalaxisfieldDropDownLabel.Text = 'Horizontal axis field';
            app.HorizontalaxisfieldDropDown = uidropdown(app.GridLayoutInputHorizontalAxis);
            app.HorizontalaxisfieldDropDown.Items = {'H [A/m]', 'H [kA/m]', 'H [Oe]', 'H [kOe]', 'Bext [T]', 'Bext [G]', 'Bext [kG]'};
            app.HorizontalaxisfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @HorizontalaxisfieldDropDownValueChanged, true);
            app.HorizontalaxisfieldDropDown.Value = 'H [A/m]';
            app.GridLayoutInputVerticalAxis = uigridlayout(app.GridLayoutInput);
            app.VerticalaxisfieldDropDownLabel = uilabel(app.GridLayoutInputVerticalAxis);
            app.VerticalaxisfieldDropDownLabel.Text = 'Vertical axis field';
            app.VerticalaxisfieldDropDown = uidropdown(app.GridLayoutInputVerticalAxis);
            app.VerticalaxisfieldDropDown.Items = {'M [A/m]', 'M [kA/m]', 'M [MA/m]', 'M [emu/cm^3]', 'J [T]', 'B [T]', 'B [G]', 'B [kG]'};
            app.VerticalaxisfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @VerticalaxisfieldDropDownValueChanged, true);
            app.VerticalaxisfieldDropDown.Value = 'M [A/m]';
            app.GridLayoutInputCurve = uigridlayout(app.GridLayoutInput);
            app.CurveDropDownLabel = uilabel(app.GridLayoutInputCurve);
            app.CurveDropDownLabel.Text = 'Curve';
            app.CurveDropDown = uidropdown(app.GridLayoutInputCurve);
            app.CurveDropDown.Items = {'Anhysteretic curve', 'Hysteresis loop'};
            app.CurveDropDown.ValueChangedFcn = createCallbackFcn(app, @CurveDropDownValueChanged, true);
            app.CurveDropDown.Value = 'Anhysteretic curve';
            app.GridLayout = uigridlayout(app.GridLayoutInput);
            app.DescriptionTextArea = uitextarea(app.GridLayout);
            app.DescriptionLabel = uilabel(app.GridLayoutInput);
            app.DescriptionLabel.Text = 'Description';
            app.GridLayoutDatasetPath = uigridlayout(app.GridLayoutInput);
            app.InputDatasetpathLabel = uilabel(app.GridLayoutDatasetPath);
            app.InputDatasetpathLabel.Text = 'Dataset path';
            app.InputBrowseButton = uibutton(app.GridLayoutDatasetPath, 'push');
            app.InputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @InputBrowseButtonPushed, true);
            app.InputBrowseButton.Text = 'Browse';
            app.InputDatasetPath = uieditfield(app.GridLayoutDatasetPath, 'text');
            app.InputDatasetPath.ValueChangedFcn = createCallbackFcn(app, @InputDatasetPathValueChanged, true);
            app.GridLayoutInputPlot = uigridlayout(app.GridLayoutMagnetizationInputData);
            app.GridLayoutInputAxisScale = uigridlayout(app.GridLayoutInputPlot);
            app.InputAxisScaleDropDown = uidropdown(app.GridLayoutInputAxisScale);
            app.InputAxisScaleDropDown.Items = {'linear', 'semilog-x', 'semilog-y', 'log-log'};
            app.InputAxisScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @InputAxisScaleDropDownValueChanged2, true);
            app.InputAxisScaleDropDown.Tag = 'InputAxisScaleDropDown';
            app.InputAxisScaleDropDown.Value = 'linear';
            app.InputNumberofPointsLabel = uilabel(app.GridLayoutInputAxisScale);
            app.InputNumberofPointsLabel.Text = 'Number of points';
            app.AxisscaleLabel = uilabel(app.GridLayoutInputAxisScale);
            app.AxisscaleLabel.Text = 'Axis scale';
            app.InputApplyPointsButton = uibutton(app.GridLayoutInputAxisScale, 'push');
            app.InputApplyPointsButton.ButtonPushedFcn = createCallbackFcn(app, @InputApplyPointsButtonPushed, true);
            app.InputApplyPointsButton.Text = 'Apply';
            app.InputNumberofPointsEditField = uieditfield(app.GridLayoutInputAxisScale, 'numeric');
            app.InputNumberofPointsEditField.Limits = [2 Inf];
            app.InputNumberofPointsEditField.RoundFractionalValues = 'on';
            app.InputNumberofPointsEditField.ValueDisplayFormat = '%.0f';
            app.InputNumberofPointsEditField.Value = 50;
            app.GridLayoutInputPlots = uigridlayout(app.GridLayoutInputPlot);
            app.AxesProcessedInputData = uiaxes(app.GridLayoutInputPlots);
            title(app.AxesProcessedInputData, 'Processed input data')
            app.AxesRawInputData = uiaxes(app.GridLayoutInputPlots);
            title(app.AxesRawInputData, 'Raw input data')
            zlabel(app.AxesRawInputData, 'Z')
            app.GridLayoutInputTipsAndPlotButton = uigridlayout(app.GridLayoutInputPlot);
            app.GridLayoutTips = uigridlayout(app.GridLayoutInputTipsAndPlotButton);
            app.HtipAmLabel = uilabel(app.GridLayoutTips);
            app.HtipAmLabel.Text = 'Htip [A/m]';
            app.HTipField = uieditfield(app.GridLayoutTips, 'text');
            app.HTipField.Editable = 'off';
            app.MtipAmLabel = uilabel(app.GridLayoutTips);
            app.MtipAmLabel.Text = 'Mtip [A/m]';
            app.MTipField = uieditfield(app.GridLayoutTips, 'text');
            app.MTipField.Editable = 'off';
            app.AnhystereticmagnetizationfittingTab = uitab(app.TabGroup);
            app.AnhystereticmagnetizationfittingTab.Title = 'Anhysteretic magnetization fitting';
            app.AnhystereticmagnetizationfittingTabGridLayout = uigridlayout(app.AnhystereticmagnetizationfittingTab);
            app.GridLayoutAxes = uigridlayout(app.AnhystereticmagnetizationfittingTabGridLayout);
            app.AxesM = uiaxes(app.GridLayoutAxes);
            xlabel(app.AxesM, 'H [A/m]')
            ylabel(app.AxesM, 'M [A/m]')
            zlabel(app.AxesM, 'Z')
            app.AxesdMdH = uiaxes(app.GridLayoutAxes);
            xlabel(app.AxesdMdH, 'H [A/m]')
            ylabel(app.AxesdMdH, '∂M/∂H')
            zlabel(app.AxesdMdH, 'Z')
            app.AxesHdMdH = uiaxes(app.GridLayoutAxes);
            xlabel(app.AxesHdMdH, 'H [A/m]')
            ylabel(app.AxesHdMdH, '∂M/∂(lnH) [A/m]')
            zlabel(app.AxesHdMdH, 'Z')
            app.GridLayoutOptionsM = uigridlayout(app.GridLayoutAxes);
            app.ResidualplotButtonM = uibutton(app.GridLayoutOptionsM, 'push');
            app.ResidualplotButtonM.ButtonPushedFcn = createCallbackFcn(app, @ResidualplotButtonMPushed, true);
            app.ResidualplotButtonM.Text = 'Residual plot';
            app.PlotcomponentsCheckBoxM = uicheckbox(app.GridLayoutOptionsM);
            app.PlotcomponentsCheckBoxM.ValueChangedFcn = createCallbackFcn(app, @PlotcomponentsCheckBoxMValueChanged, true);
            app.PlotcomponentsCheckBoxM.Text = 'Plot components';
            app.PlotcomponentsCheckBoxM.Value = true;
            app.ShowgridCheckBoxM = uicheckbox(app.GridLayoutOptionsM);
            app.ShowgridCheckBoxM.ValueChangedFcn = createCallbackFcn(app, @ShowgridCheckBoxMValueChanged, true);
            app.ShowgridCheckBoxM.Text = 'Show grid';
            app.ShowgridCheckBoxM.Value = true;
            app.SetColorsButton = uibutton(app.GridLayoutOptionsM, 'push');
            app.SetColorsButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorsButtonPushed, true);
            app.SetColorsButton.Text = 'Set Colors';
            app.AxisscaleLabel_2 = uilabel(app.GridLayoutOptionsM);
            app.AxisscaleLabel_2.Text = 'Axis scale';
            app.AxisScaleDropDownM = uidropdown(app.GridLayoutOptionsM);
            app.AxisScaleDropDownM.Items = {'linear', 'semilog-x', 'semilog-y', 'log-log'};
            app.AxisScaleDropDownM.ValueChangedFcn = createCallbackFcn(app, @AxisScaleDropDownMValueChanged, true);
            app.AxisScaleDropDownM.Tag = 'InputAxisScaleDropDown';
            app.AxisScaleDropDownM.Value = 'semilog-x';
            app.ShowhcrCheckBoxM = uicheckbox(app.GridLayoutOptionsM);
            app.ShowhcrCheckBoxM.ValueChangedFcn = createCallbackFcn(app, @ShowhcrCheckBoxMValueChanged, true);
            app.ShowhcrCheckBoxM.Text = 'Show Hcr';
            app.ShowhcrCheckBoxM.Value = true;
            app.GridLayoutOptionsdMdH = uigridlayout(app.GridLayoutAxes);
            app.ResidualplotButtondMdH = uibutton(app.GridLayoutOptionsdMdH, 'push');
            app.ResidualplotButtondMdH.ButtonPushedFcn = createCallbackFcn(app, @ResidualplotButtondMdHPushed, true);
            app.ResidualplotButtondMdH.Text = 'Residual plot';
            app.PlotcomponentsCheckBoxdMdH = uicheckbox(app.GridLayoutOptionsdMdH);
            app.PlotcomponentsCheckBoxdMdH.ValueChangedFcn = createCallbackFcn(app, @PlotcomponentsCheckBoxdMdHValueChanged, true);
            app.PlotcomponentsCheckBoxdMdH.Text = 'Plot components';
            app.PlotcomponentsCheckBoxdMdH.Value = true;
            app.ShowgridCheckBoxdMdH = uicheckbox(app.GridLayoutOptionsdMdH);
            app.ShowgridCheckBoxdMdH.ValueChangedFcn = createCallbackFcn(app, @ShowgridCheckBoxdMdHValueChanged, true);
            app.ShowgridCheckBoxdMdH.Text = 'Show grid';
            app.ShowgridCheckBoxdMdH.Value = true;
            app.AxisscaleLabel_3 = uilabel(app.GridLayoutOptionsdMdH);
            app.AxisscaleLabel_3.Text = 'Axis scale';
            app.AxisScaleDropDowndMdH = uidropdown(app.GridLayoutOptionsdMdH);
            app.AxisScaleDropDowndMdH.Items = {'linear', 'semilog-x', 'semilog-y', 'log-log'};
            app.AxisScaleDropDowndMdH.ValueChangedFcn = createCallbackFcn(app, @AxisScaleDropDowndMdHValueChanged, true);
            app.AxisScaleDropDowndMdH.Tag = 'InputAxisScaleDropDown';
            app.AxisScaleDropDowndMdH.Value = 'semilog-x';
            app.ShowhcrCheckBoxdMdH = uicheckbox(app.GridLayoutOptionsdMdH);
            app.ShowhcrCheckBoxdMdH.ValueChangedFcn = createCallbackFcn(app, @ShowhcrCheckBoxdMdHValueChanged, true);
            app.ShowhcrCheckBoxdMdH.Text = 'Show Hcr';
            app.ShowhcrCheckBoxdMdH.Value = true;
            app.GridLayoutOptionsHdMdH = uigridlayout(app.GridLayoutAxes);
            app.ResidualplotButtondHdMdH = uibutton(app.GridLayoutOptionsHdMdH, 'push');
            app.ResidualplotButtondHdMdH.ButtonPushedFcn = createCallbackFcn(app, @ResidualplotButtondHdMdHPushed, true);
            app.ResidualplotButtondHdMdH.Text = 'Residual plot';
            app.PlotcomponentsCheckBoxHdMdH = uicheckbox(app.GridLayoutOptionsHdMdH);
            app.PlotcomponentsCheckBoxHdMdH.ValueChangedFcn = createCallbackFcn(app, @PlotcomponentsCheckBoxHdMdHValueChanged, true);
            app.PlotcomponentsCheckBoxHdMdH.Text = 'Plot components';
            app.PlotcomponentsCheckBoxHdMdH.Value = true;
            app.ShowgridCheckBoxHdMdH = uicheckbox(app.GridLayoutOptionsHdMdH);
            app.ShowgridCheckBoxHdMdH.ValueChangedFcn = createCallbackFcn(app, @ShowgridCheckBoxHdMdHValueChanged, true);
            app.ShowgridCheckBoxHdMdH.Text = 'Show grid';
            app.ShowgridCheckBoxHdMdH.Value = true;
            app.AxisScaleDropDownHdMdH = uidropdown(app.GridLayoutOptionsHdMdH);
            app.AxisScaleDropDownHdMdH.Items = {'linear', 'semilog-x', 'semilog-y', 'log-log'};
            app.AxisScaleDropDownHdMdH.ValueChangedFcn = createCallbackFcn(app, @AxisScaleDropDownHdMdHValueChanged, true);
            app.AxisScaleDropDownHdMdH.Tag = 'InputAxisScaleDropDown';
            app.AxisScaleDropDownHdMdH.Value = 'semilog-x';
            app.AxisscaleLabel_4 = uilabel(app.GridLayoutOptionsHdMdH);
            app.AxisscaleLabel_4.Text = 'Axis scale';
            app.ShowhcrCheckBoxHdMdH = uicheckbox(app.GridLayoutOptionsHdMdH);
            app.ShowhcrCheckBoxHdMdH.ValueChangedFcn = createCallbackFcn(app, @ShowhcrCheckBoxHdMdHValueChanged, true);
            app.ShowhcrCheckBoxHdMdH.Text = 'Show Hcr';
            app.ShowhcrCheckBoxHdMdH.Value = true;
            app.GridLayoutNumbers = uigridlayout(app.AnhystereticmagnetizationfittingTabGridLayout);
            app.TableFittedParameters = uitable(app.GridLayoutNumbers);
            app.TableFittedParameters.ColumnName = {'Parameter'; 'Value'; 'Lower bound'; 'Upper bound'; 'Fit'};
            app.TableFittedParameters.RowName = {};
            app.TableFittedParameters.ColumnEditable = [false true true true true];
            app.TableFittedParameters.CellEditCallback = createCallbackFcn(app, @TableFittedParametersCellEdit, true);
            app.TableFittedParameters.CellSelectionCallback = createCallbackFcn(app, @TableFittedParametersCellSelection, true);
            app.ModelretrievedparametersLabel = uilabel(app.GridLayoutNumbers);
            app.ModelretrievedparametersLabel.Text = 'Model retrieved parameters';
            app.TableParameters = uitable(app.GridLayoutNumbers);
            app.TableParameters.ColumnName = {'Component'; 'Msᵢ [A/m]'; 'αᵢ'; 'aᵢ [A/m]'; 'Select aᵢ'};
            app.TableParameters.RowName = {};
            app.TableParameters.ColumnEditable = [false false false false true];
            app.GridLayoutButtons = uigridlayout(app.GridLayoutNumbers);
            app.FitButton = uibutton(app.GridLayoutButtons, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.CalculatePlotButton = uibutton(app.GridLayoutButtons, 'push');
            app.CalculatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePlotButtonPushed, true);
            app.CalculatePlotButton.Text = 'Calculate & Plot';
            app.ErrortominimizeDropDownLabel = uilabel(app.GridLayoutButtons);
            app.ErrortominimizeDropDownLabel.Text = 'Error to minimize';
            app.ErrortominimizeDropDown = uidropdown(app.GridLayoutButtons);
            app.ErrortominimizeDropDown.Items = {'Diagonal', 'Vertical', 'Horizontal'};
            app.ErrortominimizeDropDown.Value = 'Diagonal';
            app.ErrorDisplay = uieditfield(app.GridLayoutButtons, 'text');
            app.ErrorDisplay.Editable = 'off';
            app.GridLayoutOtherQuantities = uigridlayout(app.GridLayoutNumbers);
            app.OthercalculatedquantitiesLabel = uilabel(app.GridLayoutOtherQuantities);
            app.OthercalculatedquantitiesLabel.Text = 'Other calculated quantities';
            app.JsTEditFieldLabel = uilabel(app.GridLayoutOtherQuantities);
            app.JsTEditFieldLabel.Text = 'Js [T]';
            app.JsField = uieditfield(app.GridLayoutOtherQuantities, 'text');
            app.JsField.Editable = 'off';
            app.murinField = uieditfield(app.GridLayoutOtherQuantities, 'text');
            app.murinField.Editable = 'off';
            app.murinLabel = uilabel(app.GridLayoutOtherQuantities);
            app.murinLabel.Text = 'μrᵢₙ';
            app.TableQuantities = uitable(app.GridLayoutNumbers);
            app.TableQuantities.ColumnName = {'Component'; 'αᵢ⏐Msᵢ⏐/(3aᵢ)'; 'NᵢkвT [J/m³]'; 'Hkᵢ [A/m]'; 'μrᵢₙ ᵢ'};
            app.TableQuantities.RowName = {};
            app.ModeledcurveLabel = uilabel(app.GridLayoutNumbers);
            app.ModeledcurveLabel.Text = 'Modeled curve';
            app.GridLayoutModeledCurve = uigridlayout(app.GridLayoutNumbers);
            app.NumberofcomponentsSpinnerLabel = uilabel(app.GridLayoutModeledCurve);
            app.NumberofcomponentsSpinnerLabel.Text = 'Number of components';
            app.NumberofcomponentsSpinner = uispinner(app.GridLayoutModeledCurve);
            app.NumberofcomponentsSpinner.Limits = [1 4];
            app.NumberofcomponentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentsSpinnerValueChanged, true);
            app.NumberofcomponentsSpinner.Value = 1;
            app.NumberofpointsEditFieldLabel = uilabel(app.GridLayoutModeledCurve);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.GridLayoutModeledCurve, 'numeric');
            app.NumberofpointsEditField.Limits = [0 Inf];
            app.NumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsEditField.Value = 100;
            app.PointSpaceDropDown = uidropdown(app.GridLayoutModeledCurve);
            app.PointSpaceDropDown.Items = {'Logarithmically spaced', 'Lineraly spaced'};
            app.PointSpaceDropDown.ItemsData = {'log', 'linear'};
            app.PointSpaceDropDown.Value = 'log';
            app.FittedparametersLabel = uilabel(app.GridLayoutNumbers);
            app.FittedparametersLabel.Text = 'Fitted parameters';
            app.MagnetizationoutputdataTab = uitab(app.TabGroup);
            app.MagnetizationoutputdataTab.Title = 'Magnetization output data';
            app.GridLayoutMagnetizationoutputdata = uigridlayout(app.MagnetizationoutputdataTab);
            app.GridLayoutOutputDatasetPath = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.OutputDatasetpathLabel = uilabel(app.GridLayoutOutputDatasetPath);
            app.OutputDatasetpathLabel.Text = 'Dataset path';
            app.OutputDatasetPath = uieditfield(app.GridLayoutOutputDatasetPath, 'text');
            app.OutputBrowseButton = uibutton(app.GridLayoutOutputDatasetPath, 'push');
            app.OutputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OutputBrowseButtonPushed, true);
            app.OutputBrowseButton.Text = 'Browse';
            app.MagnetizationdataLabel = uilabel(app.GridLayoutMagnetizationoutputdata);
            app.MagnetizationdataLabel.Text = 'Magnetization data:';
            app.GridLayoutMagnetizationDataFittedAnhystereticMagnetization = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization = uicheckbox(app.GridLayoutMagnetizationDataFittedAnhystereticMagnetization);
            app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization.Text = '';
            app.CheckBoxOutputMagnetizationDataFittedAnhystereticMagnetization.Value = true;
            app.ModeledanhystereticmagnetizationLabel = uilabel(app.GridLayoutMagnetizationDataFittedAnhystereticMagnetization);
            app.ModeledanhystereticmagnetizationLabel.Text = 'Modeled anhysteretic magnetization';
            app.EditFieldFileNameModeledAnhystereticMagnetization = uieditfield(app.GridLayoutMagnetizationDataFittedAnhystereticMagnetization, 'text');
            app.EditFieldFileNameModeledAnhystereticMagnetization.Value = 'modeled_anhysteretic_magnetization';
            app.DropDownOutputModeledAnhystereticMagnetizationExtension = uidropdown(app.GridLayoutMagnetizationDataFittedAnhystereticMagnetization);
            app.DropDownOutputModeledAnhystereticMagnetizationExtension.Items = {'.csv'};
            app.DropDownOutputModeledAnhystereticMagnetizationExtension.Value = '.csv';
            app.GridLayoutExportData = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.ExportdataButton = uibutton(app.GridLayoutExportData, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.OutputSeparateComponentsCheckBox = uicheckbox(app.GridLayoutExportData);
            app.OutputSeparateComponentsCheckBox.Text = '';
            app.OutputSeparateComponentsCheckBox.Value = true;
            app.ModeledanhystereticmagnetizationcomponentsLabel = uilabel(app.GridLayoutExportData);
            app.ModeledanhystereticmagnetizationcomponentsLabel.Text = 'Modeled anhysteretic magnetization components';
            app.ParametersLabel = uilabel(app.GridLayoutMagnetizationoutputdata);
            app.ParametersLabel.Text = 'Parameters:';
            app.GridLayoutExportParametersFile = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.EditFieldFileNameParameters = uieditfield(app.GridLayoutExportParametersFile, 'text');
            app.EditFieldFileNameParameters.Value = 'parameters';
            app.DropDownOutputParametersExtension = uidropdown(app.GridLayoutExportParametersFile);
            app.DropDownOutputParametersExtension.Items = {'.txt'};
            app.DropDownOutputParametersExtension.Value = '.txt';
            app.ExportFittedparametersCheckBox = uicheckbox(app.GridLayoutExportParametersFile);
            app.ExportFittedparametersCheckBox.Text = 'Fitted parameters';
            app.ExportFittedparametersCheckBox.Value = true;
            app.ExportModelparametersCheckBox = uicheckbox(app.GridLayoutExportParametersFile);
            app.ExportModelparametersCheckBox.Text = 'Model parameters';
            app.ExportModelparametersCheckBox.Value = true;
            app.GridLayoutExportParametersButton = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.ExportParametersButton = uibutton(app.GridLayoutExportParametersButton, 'push');
            app.ExportParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportParametersButtonPushed, true);
            app.ExportParametersButton.Text = 'Export data';
            app.ExportOtherquantitiesCheckBox = uicheckbox(app.GridLayoutExportParametersButton);
            app.ExportOtherquantitiesCheckBox.Text = 'Other quantities';
            app.ExportOtherquantitiesCheckBox.Value = true;
            app.ExportErrorsCheckBox = uicheckbox(app.GridLayoutExportParametersButton);
            app.ExportErrorsCheckBox.Text = 'Errors';
            app.ExportErrorsCheckBox.Value = true;
            app.PlotsLabel = uilabel(app.GridLayoutMagnetizationoutputdata);
            app.PlotsLabel.Text = 'Plots:';
            app.GridLayoutExportPlotMagnetization = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.MagnetizationPlotExportLabel = uilabel(app.GridLayoutExportPlotMagnetization);
            app.MagnetizationPlotExportLabel.Text = 'Magnetization';
            app.CheckBoxExportPlotMagnetization = uicheckbox(app.GridLayoutExportPlotMagnetization);
            app.CheckBoxExportPlotMagnetization.Text = '';
            app.CheckBoxExportPlotMagnetization.Value = true;
            app.EditFieldFileNamePlotMagnetization = uieditfield(app.GridLayoutExportPlotMagnetization, 'text');
            app.EditFieldFileNamePlotMagnetization.Value = 'M';
            app.DropDownPlotMagnetizacionExtension = uidropdown(app.GridLayoutExportPlotMagnetization);
            app.DropDownPlotMagnetizacionExtension.Items = {'.png'};
            app.DropDownPlotMagnetizacionExtension.Value = '.png';
            app.GridLayoutExportPlotSusceptibility = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.SusceptibilityPlotExportLabel = uilabel(app.GridLayoutExportPlotSusceptibility);
            app.SusceptibilityPlotExportLabel.Text = 'Susceptibility';
            app.CheckBoxExportPlotSusceptibility = uicheckbox(app.GridLayoutExportPlotSusceptibility);
            app.CheckBoxExportPlotSusceptibility.Text = '';
            app.CheckBoxExportPlotSusceptibility.Value = true;
            app.EditFieldFileNamePlotSusceptibility = uieditfield(app.GridLayoutExportPlotSusceptibility, 'text');
            app.EditFieldFileNamePlotSusceptibility.Value = 'dMdH';
            app.DropDownPlotSusceptibilityExtension = uidropdown(app.GridLayoutExportPlotSusceptibility);
            app.DropDownPlotSusceptibilityExtension.Items = {'.png'};
            app.DropDownPlotSusceptibilityExtension.Value = '.png';
            app.GridLayoutExportPlotSemiLogMagDerivative = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.SemilogmagnetizationderivativePlotExportLabel = uilabel(app.GridLayoutExportPlotSemiLogMagDerivative);
            app.SemilogmagnetizationderivativePlotExportLabel.Text = 'Semi-log magnetization derivative';
            app.CheckBoxExportPlotSemiLogMagDerivative = uicheckbox(app.GridLayoutExportPlotSemiLogMagDerivative);
            app.CheckBoxExportPlotSemiLogMagDerivative.Text = '';
            app.CheckBoxExportPlotSemiLogMagDerivative.Value = true;
            app.EditFieldFileNamePlotSemiLogMagDerivative = uieditfield(app.GridLayoutExportPlotSemiLogMagDerivative, 'text');
            app.EditFieldFileNamePlotSemiLogMagDerivative.Value = 'dMdlnH';
            app.DropDownPlotSemiLogMagDerivativeExtension = uidropdown(app.GridLayoutExportPlotSemiLogMagDerivative);
            app.DropDownPlotSemiLogMagDerivativeExtension.Items = {'.png'};
            app.DropDownPlotSemiLogMagDerivativeExtension.Value = '.png';
            app.GridLayoutExportPlotsButton = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.ExportPlotsButton = uibutton(app.GridLayoutExportPlotsButton, 'push');
            app.ExportPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotsButtonPushed, true);
            app.ExportPlotsButton.Text = 'Export plots';
            app.GridLayoutExportResiduesMagnetization = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.MagnetizationExportResiduesLabel = uilabel(app.GridLayoutExportResiduesMagnetization);
            app.MagnetizationExportResiduesLabel.Text = 'Magnetization';
            app.CheckBoxExportResiduesMagnetization = uicheckbox(app.GridLayoutExportResiduesMagnetization);
            app.CheckBoxExportResiduesMagnetization.Text = '';
            app.CheckBoxExportResiduesMagnetization.Value = true;
            app.EditFieldFileNameResiduesMagnetization = uieditfield(app.GridLayoutExportResiduesMagnetization, 'text');
            app.EditFieldFileNameResiduesMagnetization.Value = 'residual_M';
            app.DropDownResiduesMagnetizacionExtension = uidropdown(app.GridLayoutExportResiduesMagnetization);
            app.DropDownResiduesMagnetizacionExtension.Items = {'.csv'};
            app.DropDownResiduesMagnetizacionExtension.Value = '.csv';
            app.ResidualplotsdataLabel = uilabel(app.GridLayoutMagnetizationoutputdata);
            app.ResidualplotsdataLabel.Text = 'Residual plots data:';
            app.GridLayoutExportResiduesSusceptibility = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.SusceptibilityResiduesExportLabel = uilabel(app.GridLayoutExportResiduesSusceptibility);
            app.SusceptibilityResiduesExportLabel.Text = 'Susceptibility';
            app.CheckBoxExportResiduesSusceptibility = uicheckbox(app.GridLayoutExportResiduesSusceptibility);
            app.CheckBoxExportResiduesSusceptibility.Text = '';
            app.CheckBoxExportResiduesSusceptibility.Value = true;
            app.EditFieldFileNameResiduesSusceptibility = uieditfield(app.GridLayoutExportResiduesSusceptibility, 'text');
            app.EditFieldFileNameResiduesSusceptibility.Value = 'residual_dMdH';
            app.DropDownResiduesSusceptibilityExtension = uidropdown(app.GridLayoutExportResiduesSusceptibility);
            app.DropDownResiduesSusceptibilityExtension.Items = {'.csv'};
            app.DropDownResiduesSusceptibilityExtension.Value = '.csv';
            app.GridLayoutExportResiduesSemiLogMagDerivative = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.SemilogmagnetizationderivativeResiduesExportLabel = uilabel(app.GridLayoutExportResiduesSemiLogMagDerivative);
            app.SemilogmagnetizationderivativeResiduesExportLabel.Text = 'Semi-log magnetization derivative';
            app.CheckBoxExportResiduesSemiLogMagDerivative = uicheckbox(app.GridLayoutExportResiduesSemiLogMagDerivative);
            app.CheckBoxExportResiduesSemiLogMagDerivative.Text = '';
            app.CheckBoxExportResiduesSemiLogMagDerivative.Value = true;
            app.EditFieldFileNameResiduesSemiLogMagDerivative = uieditfield(app.GridLayoutExportResiduesSemiLogMagDerivative, 'text');
            app.EditFieldFileNameResiduesSemiLogMagDerivative.Value = 'residual_dMdlnH';
            app.DropDownResiduesSemiLogMagDerivativeExtension = uidropdown(app.GridLayoutExportResiduesSemiLogMagDerivative);
            app.DropDownResiduesSemiLogMagDerivativeExtension.Items = {'.csv'};
            app.DropDownResiduesSemiLogMagDerivativeExtension.Value = '.csv';
            app.GridLayoutExportResiduesButton = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.ExportResiduesButton = uibutton(app.GridLayoutExportResiduesButton, 'push');
            app.ExportResiduesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResiduesButtonPushed, true);
            app.ExportResiduesButton.Text = 'Export data';
            app.GridLayoutExperimentalMagnetizationData = uigridlayout(app.GridLayoutMagnetizationoutputdata);
            app.CheckBoxExperimentalMagnetization = uicheckbox(app.GridLayoutExperimentalMagnetizationData);
            app.CheckBoxExperimentalMagnetization.Text = '';
            app.CheckBoxExperimentalMagnetization.Value = true;
            app.ExperimentalanhystereticmagnetizationLabel = uilabel(app.GridLayoutExperimentalMagnetizationData);
            app.ExperimentalanhystereticmagnetizationLabel.Text = 'Experimental anhysteretic magnetization';
            app.EditFieldFileNameExperimentalMagnetizationData = uieditfield(app.GridLayoutExperimentalMagnetizationData, 'text');
            app.EditFieldFileNameExperimentalMagnetizationData.Value = 'experimental_anhysteretic_magnetization';
            app.DropDownOutputExperimentalMagnetizationData = uidropdown(app.GridLayoutExperimentalMagnetizationData);
            app.DropDownOutputExperimentalMagnetizationData.Items = {'.csv'};
            app.DropDownOutputExperimentalMagnetizationData.Value = '.csv';
            app.MessagesTabPanel = uitabgroup(app.AppGridLayout);
            app.MessagesTab = uitab(app.MessagesTabPanel);
            app.MessagesTab.Title = 'Messages';
            app.MessagesGridLayout = uigridlayout(app.MessagesTab);
            app.MessagesTextArea = uitextarea(app.MessagesGridLayout);
            app.MessagesTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.MagAnalystUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagAnalystUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagAnalystUIFigure)
        end
    end
end